classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UsernameEditFieldLabel     matlab.ui.control.Label
        UsernameEditField          matlab.ui.control.EditField
        PasswordEditFieldLabel     matlab.ui.control.Label
        PasswordEditField          matlab.ui.control.EditField
        DickensResearchGroupLabel  matlab.ui.control.Label
        MATERIALDATABASEv10Label   matlab.ui.control.Label
        LogInButton                matlab.ui.control.Button
        CreateNewUserButton        matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        ManageTab                  matlab.ui.container.Tab
        Image                      matlab.ui.control.Image
        BrowseTab                  matlab.ui.container.Tab
        AddTab                     matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Data % Data
        Users % Users
        State
    end
    
    methods (Access = private)
        
        function changeState(app)
            global State;
            switch State
                case 1 % bad login
                    
                case 2 % good login
                    app.UsernameEditField.Visible = 'off';
                    app.UsernameEditFieldLabel.Visible = 'off';
                    app.PasswordEditField.Visible = 'off';
                    app.PasswordEditFieldLabel.Visible = 'off';
                    app.LogInButton.Visible = 'off';
                    app.CreateNewUserButton.Visible = 'off';
                    app.DickensResearchGroupLabel.Visible = 'off';
                    app.MATERIALDATABASEv10Label.Visible = 'off';
                    
                    app.TabGroup.Visible = 'on';
%                     app.Image.Visible = 'on';
            
                case 3 % New User
                    
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            app.TabGroup.Visible = 'off';
            %                     app.Image.Visible = 'off';
            global State;
            global Users2;
            Users = webread('https://raw.githubusercontent.com/highphii/DB_v1/master/Users_v1.txt')
            State = 1;
            
            f1 = strfind(Users, ',');
            f2 = strfind(Users, '.');
            strt = 1;
            for i = 1:length(f1)
                F1 = Users(strt:f1(i)-1);
                F2 = Users(f1(i)+2:f2(i)-1);
                
                Users2 = [Users2; {F1, F2}];
                strt = f2(i)+2;
            end
        end

        % Button pushed function: LogInButton
        function loginPushed(app, event)
%             disp('Login Pushed')
            global username;
            global password;
            global State;
            global Users2;
            
            [m, ~] = size(Users2);
            for i = 1:m
                F1 = Users2{i, 1}
                F2 = Users2{i, 2}
                
                f1 = strcmp(F1, username);
                f2 = strcmp(F2, password);
                
                if (f1 == true) && (f2 == true)
                    State = 2;
                    changeState(app);
                end
                
            end
        end

        % Button pushed function: CreateNewUserButton
        function newUserPushed(app, event)
            
        end

        % Value changed function: UsernameEditField
        function getUsername(app, event)
            global username
            username = app.UsernameEditField.Value;
            
        end

        % Value changed function: PasswordEditField
        function getPassword(app, event)
            global password;
            password = app.PasswordEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UsernameEditFieldLabel = uilabel(app.UIFigure);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.UIFigure, 'text');
            app.UsernameEditField.ValueChangedFcn = createCallbackFcn(app, @getUsername, true);
            app.PasswordEditFieldLabel = uilabel(app.UIFigure);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.UIFigure, 'text');
            app.PasswordEditField.ValueChangedFcn = createCallbackFcn(app, @getPassword, true);
            app.DickensResearchGroupLabel = uilabel(app.UIFigure);
            app.DickensResearchGroupLabel.Text = 'Dickens Research Group ';
            app.MATERIALDATABASEv10Label = uilabel(app.UIFigure);
            app.MATERIALDATABASEv10Label.Text = 'MATERIAL DATABASE v1.0';
            app.LogInButton = uibutton(app.UIFigure, 'push');
            app.LogInButton.ButtonPushedFcn = createCallbackFcn(app, @loginPushed, true);
            app.LogInButton.Text = 'Log In';
            app.CreateNewUserButton = uibutton(app.UIFigure, 'push');
            app.CreateNewUserButton.ButtonPushedFcn = createCallbackFcn(app, @newUserPushed, true);
            app.CreateNewUserButton.Text = 'Create New User';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ManageTab = uitab(app.TabGroup);
            app.ManageTab.Title = 'Manage';
            app.Image = uiimage(app.ManageTab);
            app.Image.ImageSource = 'image (4).png';
            app.BrowseTab = uitab(app.TabGroup);
            app.BrowseTab.Title = 'Browse';
            app.AddTab = uitab(app.TabGroup);
            app.AddTab.Title = 'Add';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end